classdef AirQuality < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AirQualityAppUIFigure        matlab.ui.Figure
        AirQualityConditionsLabel    matlab.ui.control.Label
        AirQualityLabel              matlab.ui.control.Label
        DayForecastPanel             matlab.ui.container.Panel
        CurrentConditionsPanel       matlab.ui.container.Panel
        LocationCityBostonEditFieldLabel  matlab.ui.control.Label
        LocationCityBostonEditField  matlab.ui.control.EditField
        UpdateButton                 matlab.ui.control.StateButton
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        Key         % Api key
        Model       % Machine learning model
        Label       % Air quality label
        currentData
        forecastData        
    end
    
    methods (Access = private)
        
        function predictAirQualLabel(app,data)
       
            % Determine air quality
            airQual = predict(app.Model,data);
                        
            % Convert data type 
            app.Label = string(airQual);
                        
            % display
            app.AirQualityLabel.Text = "Air Quality: "+app.Label;
            
            LabelColor(app);            
            
        end
                
        function plotForecast(app)
            stackedplot(app.DayForecastPanel,app.forecastData(:,1:end-2));            
        end
        
        function resetAppVisuals(app)             
            % Reset visuals while searching and calculating
            if ~isempty(app.DayForecastPanel.Children)
                delete(app.DayForecastPanel.Children);
            end
            app.AirQualityLabel.BackgroundColor = [0.94 0.94 0.94];
            app.AirQualityLabel.Text = "Air Quality:";
            
            app.UITable.Visible = "off";
        end
        
        function LabelColor(app)
            % Update background color based on air quality label
            
            switch app.Label
                case "Good"
                    app.AirQualityLabel.BackgroundColor = [0 0.75 0]; % green
                case "Moderate"
                    app.AirQualityLabel.BackgroundColor = [0.9290 0.6940 0.1250]; % yellow  %[0.4660 0.6740 0.1880]; % yellow green
                case "Unhealthy for sensitive groups"
                    app.AirQualityLabel.BackgroundColor = [0.8500 0.3250 0.0980]; %orange/red
                case "Unhealthy"
                    app.AirQualityLabel.BackgroundColor = [0.75 0 0];  % red
                case "Very unhealthy"
                    app.AirQualityLabel.BackgroundColor = [0.6350 0.0780 0.1840];  % dark red
                case "Hazardous"                     
                    app.AirQualityLabel.BackgroundColor = [0.70 0.0780 0.1840];  % dark red
            end
            
        end
        
        function ReadForecastData(app)
            % Get the input
            value = app.LocationCityBostonEditField.Value;
            % Read data 
%             try
                [app.currentData,app.forecastData] = ReadDataFromWeb(value,app.Key(1)); 
%             catch
%                 errordlg("Error during read, try entering location differently")
%             end
                
            % Update table
            app.UITable.Visible = "on";
            app.UITable.Data = app.currentData(:,3:8);
            app.UITable.ColumnName = ["Temperature","Pressure","DewPoint",...
                "RelativeHumidity","WindDir","WindSpd"];            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                       
            app.UITable.Visible = "off";
            
            % Load model
            mdl = load("airQualModel.mat");
            app.Model = mdl.model;
            
            % Read api key 
            t = readtable("accessKey.txt","TextType","string");  
            app.Key = t.Key;

        end

        % Value changed function: LocationCityBostonEditField
        function LocationCityBostonEditFieldValueChanged(app, event)
            d = uiprogressdlg(app.AirQualityAppUIFigure,'Title','Please wait','Message','Updating...');
            
            % Reset the visuals
            resetAppVisuals(app)
            
            % Read data from API
            ReadForecastData(app);
            
            % Predict
            predictAirQualLabel(app,app.currentData);
            
            % plot forecast
            plotForecast(app);
            
            close(d); 
            
        end

        % Value changed function: UpdateButton
        function UpdateButtonValueChanged(app, event)
            
            d = uiprogressdlg(app.AirQualityAppUIFigure,'Title','Please wait','Message','Updating...');
            
            % Reset the visuals
            resetAppVisuals(app)
           
            % Read data from API
            ReadForecastData(app);
            
            % Predict
            predictAirQualLabel(app,app.currentData);            
            
            % plot forecast
            plotForecast(app);
            
            close(d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AirQualityAppUIFigure = uifigure('Visible', 'off');
            app.AirQualityAppUIFigure.Name = 'AirQualityApp';
            app.AirQualityConditionsLabel = uilabel(app.AirQualityAppUIFigure);
            app.AirQualityConditionsLabel.Text = 'Air Quality Conditions';
            app.AirQualityLabel = uilabel(app.AirQualityAppUIFigure);
            app.AirQualityLabel.Text = 'Air Quality: ';
            app.DayForecastPanel = uipanel(app.AirQualityAppUIFigure);
            app.DayForecastPanel.Title = '10 - Day Forecast';
            app.CurrentConditionsPanel = uipanel(app.AirQualityAppUIFigure);
            app.CurrentConditionsPanel.Title = 'Current Conditions';
            app.LocationCityBostonEditFieldLabel = uilabel(app.CurrentConditionsPanel);
            app.LocationCityBostonEditFieldLabel.Text = 'Location: City (Boston)';
            app.LocationCityBostonEditField = uieditfield(app.CurrentConditionsPanel, 'text');
            app.LocationCityBostonEditField.ValueChangedFcn = createCallbackFcn(app, @LocationCityBostonEditFieldValueChanged, true);
            app.UpdateButton = uibutton(app.CurrentConditionsPanel, 'state');
            app.UpdateButton.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonValueChanged, true);
            app.UpdateButton.Text = 'Update';
            app.UITable = uitable(app.CurrentConditionsPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.AirQualityAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AirQuality

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AirQualityAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AirQualityAppUIFigure)
        end
    end
end